classdef PRMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AccountMenu                    matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        SettingMenu                    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        PerformanceAnalysisTab         matlab.ui.container.Tab
        PositionsPanel                 matlab.ui.container.Panel
        PositionTable                  matlab.ui.control.Table
        PerformancePanel               matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        PNLTab                         matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        PriceSeriesTab                 matlab.ui.container.Tab
        ProjectionTab                  matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        ReinvestCheckBox               matlab.ui.control.CheckBox
        TransactionCostCheckBox        matlab.ui.control.CheckBox
        ApplyButton                    matlab.ui.control.Button
        StartDateDatePicker_2          matlab.ui.control.DatePicker
        StartDateDatePicker_2Label     matlab.ui.control.Label
        EndDateDatePicker_2            matlab.ui.control.DatePicker
        EndDateDatePicker_2Label       matlab.ui.control.Label
        StatisticsTextArea             matlab.ui.control.TextArea
        StatisticsTextAreaLabel        matlab.ui.control.Label
        DisplayScaleDropDown           matlab.ui.control.DropDown
        DisplayScaleDropDownLabel      matlab.ui.control.Label
        EnteryourInitalcapitalEditField  matlab.ui.control.NumericEditField
        EnteryourInitalcapitalEditFieldLabel  matlab.ui.control.Label
        PortfolioDropDown              matlab.ui.control.DropDown
        PortfolioDropDownLabel         matlab.ui.control.Label
        FinalCapitalEditFieldLabel     matlab.ui.control.Label
        FinalCapitalEditField          matlab.ui.control.NumericEditField
        RiskManagementTab              matlab.ui.container.Tab
        VaRandESBacktestingPanel       matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        PlotButton                     matlab.ui.control.Button
        StartDateDatePickerLabel       matlab.ui.control.Label
        StartDateDatePicker            matlab.ui.control.DatePicker
        EndDateDatePickerLabel         matlab.ui.control.Label
        EndDateDatePicker              matlab.ui.control.DatePicker
        VaRLevelEditFieldLabel         matlab.ui.control.Label
        VaRLevelEditField              matlab.ui.control.NumericEditField
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        RiskMatrixPanel                matlab.ui.container.Panel
        RIskMetricsDropDownLabel       matlab.ui.control.Label
        RIskMetricsDropDown            matlab.ui.control.DropDown
        UpdateButton_2                 matlab.ui.control.Button
        UITable3                       matlab.ui.control.Table
        UnderlyingScaleEditFieldLabel  matlab.ui.control.Label
        UnderlyingScaleEditField       matlab.ui.control.EditField
        VolScaleEditFieldLabel         matlab.ui.control.Label
        VolScaleEditField              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = readtable('position.csv');
            app.PositionTable.Data = data;
            app.PositionTable.ColumnName = data.Properties.VariableNames;    
            data = table2array(data(:,2:9));
            [row, col] = find(data(:,8) > 0);
            col = col + 8;
            s = uistyle('BackgroundColor','green');
            addStyle(app.PositionTable,s,'cell',[row,col]);
            [row, col] = find(data(:,8) < 0);
            col = col + 8;
            s = uistyle('BackgroundColor','red');
            addStyle(app.PositionTable,s,'cell',[row,col]);            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            load ESBacktestBySimData
            VaRInd = 2;
            plot(app.UIAxes4, Dates,Returns,Dates,-VaR(:,VaRInd),Dates,-ES(:,VaRInd))
            legend(app.UIAxes4, 'Returns','VaR','ES')
            title(app.UIAxes4, ['Risk Metrics, ' num2str(VaRLevel(VaRInd)*100) '% Confidence'])
            ylabel(app.UIAxes4, 'Level')
            xlabel(app.UIAxes4, 'Date')
            
            load VaRExampleData.mat
            Returns = tick2ret(sp);
            DateReturns = dates(2:end);
            SampleSize = length(Returns);
            TestWindowStart      = find(year(DateReturns)==1996,1);
            TestWindow           = TestWindowStart : SampleSize;
            EstimationWindowSize = 250;
            pVaR = [0.025 0.01];
            Zscore   = norminv(pVaR);
            Normal95 = zeros(length(TestWindow),1);
            Normal99 = zeros(length(TestWindow),1);
            
            for t = TestWindow
                i = t - TestWindowStart + 1;
                EstimationWindow = t-EstimationWindowSize:t-1;
                Sigma = std(Returns(EstimationWindow));
                Normal95(i) = -Zscore(1)*Sigma;
                Normal99(i) = -Zscore(2)*Sigma;
            end
            Historical95 = zeros(length(TestWindow),1);
            Historical99 = zeros(length(TestWindow),1);
            
            for t = TestWindow
                i = t - TestWindowStart + 1;
                EstimationWindow = t-EstimationWindowSize:t-1;
                X = Returns(EstimationWindow);
                Historical95(i) = -quantile(X,pVaR(1));
                Historical99(i) = -quantile(X,pVaR(2));
            end
            Lambda = 0.94;
            Sigma2     = zeros(length(Returns),1);
            Sigma2(1)  = Returns(1)^2;
            
            for i = 2 : (TestWindowStart-1)
                Sigma2(i) = (1-Lambda) * Returns(i-1)^2 + Lambda * Sigma2(i-1);
            end
            Zscore = norminv(pVaR);
            EWMA95 = zeros(length(TestWindow),1);
            EWMA99 = zeros(length(TestWindow),1);
            
            for t = TestWindow
                k     = t - TestWindowStart + 1;
                Sigma2(t) = (1-Lambda) * Returns(t-1)^2 + Lambda * Sigma2(t-1);
                Sigma = sqrt(Sigma2(t));
                EWMA95(k) = -Zscore(1)*Sigma;
                EWMA99(k) = -Zscore(2)*Sigma;
            end
            ReturnsTest = Returns(TestWindow);
            DatesTest   = DateReturns(TestWindow);
            plot(app.UIAxes4_2, DatesTest,[ReturnsTest -Normal95 -Historical95 -EWMA95])
            ylabel(app.UIAxes4_2, 'VaR')
            xlabel(app.UIAxes4_2, 'Date')
            legend(app.UIAxes4_2, {'Returns','Normal','Historical','EWMA'},'Location','southeast')
            title(app.UIAxes4_2, 'Comparison of returns and VaR at 97.5% for different models')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            pnldata = readtable('n1m2b0c0d0output.csv')
            pnldata = pnldata(11660:13424,:)
            date = datenum(pnldata.Date)
            plot(app.UIAxes3, date, pnldata.Index_ID/pnldata.Index_ID(1));
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3, date, pnldata.Index_ED/pnldata.Index_ED(1));
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3, date, pnldata.Acc_Return/pnldata.Acc_Return(1));
            datetick(app.UIAxes3, 'x', 'yyyy')
            legend(app.UIAxes3,'Portfolio1','Portoflio2','Benchmark');
            title(app.UIAxes3, 'Strategy History PNL');
            xlabel(app.UIAxes3,'Year')
            ylabel(app.UIAxes3,'Accumulate Return');  
            app.StatisticsTextArea.FontSize = 15;
            app.StatisticsTextArea.Value = ["Yearly Return: 0.05241";
                "Yearly Volatility: 0.08125";"Information Ratio: 0.68";
                "Sharpe Ratio: 0.52";
                "Median PNL: 0.001521";
                "Mean PNL:  0.001542";
                "Maximum Dropdown: 22.6%";
                "Probability of Positive PNL: 0.524"]
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            data = readtable('matrix.csv');
            app.UITable3.Data = data;
            app.UITable3.ColumnName = data.Properties.VariableNames;    
            data = table2array(data(:,1:3));
            [row, col] = find(data > 0);
            s = uistyle('BackgroundColor','green');
            addStyle(app.UITable3,s,'cell',[row,col]);
            [row, col] = find(data < 0);
            s = uistyle('BackgroundColor','red');
            addStyle(app.UITable3,s,'cell',[row,col]);               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccountMenu = uimenu(app.UIFigure);
            app.AccountMenu.Text = 'Account';
            app.Menu_2 = uimenu(app.AccountMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.AccountMenu);
            app.Menu2.Text = 'Menu2';
            app.SettingMenu = uimenu(app.UIFigure);
            app.SettingMenu.Text = 'Setting';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PerformanceAnalysisTab = uitab(app.TabGroup);
            app.PerformanceAnalysisTab.Title = 'Performance Analysis';
            app.PositionsPanel = uipanel(app.PerformanceAnalysisTab);
            app.PositionsPanel.Title = 'Positions';
            app.PositionTable = uitable(app.PositionsPanel);
            app.PositionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PositionTable.RowName = {};
            app.PerformancePanel = uipanel(app.PerformanceAnalysisTab);
            app.PerformancePanel.Title = 'Performance ';
            app.TabGroup2 = uitabgroup(app.PerformancePanel);
            app.PNLTab = uitab(app.TabGroup2);
            app.PNLTab.Title = 'PNL';
            app.UIAxes3 = uiaxes(app.PNLTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PriceSeriesTab = uitab(app.TabGroup2);
            app.PriceSeriesTab.Title = 'Price Series';
            app.ProjectionTab = uitab(app.TabGroup2);
            app.ProjectionTab.Title = 'Projection';
            app.Panel = uipanel(app.PerformancePanel);
            app.ReinvestCheckBox = uicheckbox(app.Panel);
            app.ReinvestCheckBox.Text = 'Reinvest';
            app.TransactionCostCheckBox = uicheckbox(app.Panel);
            app.TransactionCostCheckBox.Text = 'Transaction Cost';
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StartDateDatePicker_2 = uidatepicker(app.Panel);
            app.StartDateDatePicker_2Label = uilabel(app.Panel);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.EndDateDatePicker_2 = uidatepicker(app.Panel);
            app.EndDateDatePicker_2Label = uilabel(app.Panel);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.StatisticsTextArea = uitextarea(app.Panel);
            app.StatisticsTextAreaLabel = uilabel(app.Panel);
            app.StatisticsTextAreaLabel.Text = 'Statistics';
            app.DisplayScaleDropDown = uidropdown(app.Panel);
            app.DisplayScaleDropDown.Items = {'Daily', 'Monthly', 'Yearly'};
            app.DisplayScaleDropDown.Value = 'Daily';
            app.DisplayScaleDropDownLabel = uilabel(app.Panel);
            app.DisplayScaleDropDownLabel.Text = {'Display Scale'; ''};
            app.EnteryourInitalcapitalEditField = uieditfield(app.Panel, 'numeric');
            app.EnteryourInitalcapitalEditFieldLabel = uilabel(app.Panel);
            app.EnteryourInitalcapitalEditFieldLabel.Text = 'Enter your Inital capital';
            app.PortfolioDropDown = uidropdown(app.Panel);
            app.PortfolioDropDown.Items = {'Portfolio1', 'Portfolio2', 'Portfolio3'};
            app.PortfolioDropDown.Value = 'Portfolio1';
            app.PortfolioDropDownLabel = uilabel(app.Panel);
            app.PortfolioDropDownLabel.Text = 'Portfolio';
            app.FinalCapitalEditFieldLabel = uilabel(app.Panel);
            app.FinalCapitalEditFieldLabel.Text = 'Final Capital';
            app.FinalCapitalEditField = uieditfield(app.Panel, 'numeric');
            app.RiskManagementTab = uitab(app.TabGroup);
            app.RiskManagementTab.Title = 'Risk Management';
            app.VaRandESBacktestingPanel = uipanel(app.RiskManagementTab);
            app.VaRandESBacktestingPanel.Title = 'VaR and ES Backtesting';
            app.Lamp = uilamp(app.VaRandESBacktestingPanel);
            app.PlotButton = uibutton(app.VaRandESBacktestingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StartDateDatePickerLabel = uilabel(app.VaRandESBacktestingPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.VaRandESBacktestingPanel);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.EndDateDatePickerLabel = uilabel(app.VaRandESBacktestingPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.VaRandESBacktestingPanel);
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.VaRLevelEditFieldLabel = uilabel(app.VaRandESBacktestingPanel);
            app.VaRLevelEditFieldLabel.Text = 'VaR Level';
            app.VaRLevelEditField = uieditfield(app.VaRandESBacktestingPanel, 'numeric');
            app.UIAxes4 = uiaxes(app.VaRandESBacktestingPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.VaRandESBacktestingPanel);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.RiskMatrixPanel = uipanel(app.RiskManagementTab);
            app.RiskMatrixPanel.Title = 'Risk Matrix';
            app.RIskMetricsDropDownLabel = uilabel(app.RiskMatrixPanel);
            app.RIskMetricsDropDownLabel.Text = 'RIsk Metrics';
            app.RIskMetricsDropDown = uidropdown(app.RiskMatrixPanel);
            app.RIskMetricsDropDown.Items = {'Risk Metrics', ''};
            app.RIskMetricsDropDown.Value = 'Risk Metrics';
            app.UpdateButton_2 = uibutton(app.RiskMatrixPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.UITable3 = uitable(app.RiskMatrixPanel);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.UnderlyingScaleEditFieldLabel = uilabel(app.RiskMatrixPanel);
            app.UnderlyingScaleEditFieldLabel.Text = 'Underlying Scale';
            app.UnderlyingScaleEditField = uieditfield(app.RiskMatrixPanel, 'text');
            app.VolScaleEditFieldLabel = uilabel(app.RiskMatrixPanel);
            app.VolScaleEditFieldLabel.Text = 'Vol Scale';
            app.VolScaleEditField = uieditfield(app.RiskMatrixPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end